classdef SportsCarKnowledgeBase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SPORTSCARKNOWLEDGEBASEPanel  matlab.ui.container.Panel
        ComputerTextAreaLabel        matlab.ui.control.Label
        ComputerTextArea             matlab.ui.control.TextArea
        RecOnButt                    matlab.ui.control.Button
        RecOffButt                   matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        UserTextAreaLabel            matlab.ui.control.Label
        UserTextArea                 matlab.ui.control.TextArea
        UserManualLabel              matlab.ui.control.Label
        Label                        matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function [new_table,matchStr,specquest,isNoun]=getinfo(app,T)
            specquest=[];
            x=("Please ask a question.");
            app.ComputerTextArea.Value=x;
            drawnow;
            Speak(app,x);
            
            pause(3);
            sl=recording(app);
            [sdetails,defaultString] = recognition(app,sl);
            
            x=(defaultString);
            app.UserTextArea.Value=x;
            drawnow;
            
            for i=1:height(sdetails)
                if sdetails.PartOfSpeech(i)=='proper-noun'
                    isProperNoun=gpn(app,sdetails);
                elseif sdetails.PartOfSpeech(i)=='noun'
                    isNoun=gn(app,sdetails);
                end
            end
            
            expression = '\w*est|most.(eco\w*|hor\w*)|least.(eco\w*|hor\w*)\w*';
            matchStr = regexp(defaultString,expression,'match');
            
            
            if matchStr=="fastest"
                minval=min(T.acceleration);
                for i=1:height(T)
                    if T.acceleration(i)==minval
                        new_table=T(i,:);
                    end
                end
            elseif matchStr=="slowest"
                maxval=max(T.acceleration);
                for i=1:height(T)
                    if T.acceleration(i)==maxval
                        new_table=T(i,:);
                    end
                end
            elseif matchStr=="oldest"
                minval=min(T.year);
                for i=1:height(T)
                    if T.year(i)==minval
                        new_table=T(i,:);
                    end
                end
            elseif matchStr=="newest"
                maxval=max(T.year);
                for i=1:height(T)
                    if T.year(i)==maxval
                        new_table=T(i,:);
                    end
                end
            elseif matchStr=="lightest"
                minval=min(T.weight);
                for i=1:height(T)
                    if T.weight(i)==minval
                        new_table=T(i,:);
                    end
                end
            elseif matchStr=="heaviest"
                maxval=max(T.weight);
                for i=1:height(T)
                    if T.weight(i)==maxval
                        new_table=T(i,:);
                    end
                end
            elseif matchStr=="smallest"
                minval=min(T.capacity);
                for i=1:height(T)
                    if T.capacity(i)==minval
                        new_table=T(i,:);
                    end
                end
            elseif matchStr=="largest"
                maxval=max(T.capacity);
                for i=1:height(T)
                    if T.capacity(i)==maxval
                        new_table=T(i,:);
                    end
                end
            elseif matchStr=="most economical"
                minval=min(T.averageFuelConsumption);
                for i=1:height(T)
                    if T.averageFuelConsumption(i)==minval
                        new_table=T(i,:);
                    end
                end
            elseif matchStr=="least economical"
                maxval=max(T.averageFuelConsumption);
                for i=1:height(T)
                    if T.averageFuelConsumption(i)==maxval
                        new_table=T(i,:);
                    end
                end
            elseif matchStr=="most horsepower"
                maxval=max(T.horsepower);
                for i=1:height(T)
                    if T.horsepower(i)==maxval
                        new_table=T(i,:);
                    end
                end
            elseif matchStr=="least horsepower"
                minval=min(T.horsepower);
                for i=1:height(T)
                    if T.horsepower(i)==minval
                        new_table=T(i,:);
                    end
                end
            
            
            elseif exist('isProperNoun')==1
                for i=1:height(T)
                    if T.brand(i)==isProperNoun(1) && T.model(i)==isProperNoun(2)
                        new_table=T(i,:);
                        
                        if isNoun(1)=="capacity"
                            specquest=T.capacity(i);
                        elseif isNoun(1)=="horsepower"
                            specquest=T.horsepower(i);
                        elseif isNoun(1)=="car" && isNoun(2)=="weight"
                            specquest=T.weight(i);
                        elseif isNoun(1)=="acceleration"
                            specquest=T.acceleration(i);
                        elseif isNoun(1)=="average" && isNoun(2)=="fuel" && isNoun(3)=="consumption"
                            specquest=T.averageFuelConsumption(i);
                        end
                        
                    end
                    
                end
            end
            
        end
        
        function [sdetails,defaultString] = recognition(app,samples)
            
            speechObject = speechClient('Google','languageCode','en-UK');
            outInfo = speech2text(speechObject, samples, 44100);
            defaultString = outInfo.Transcript;
            
            sentence = tokenizedDocument(defaultString);
            POSsentence = addPartOfSpeechDetails(sentence);
            sdetails = tokenDetails(POSsentence);
        end
        
        function Speak(app,text)
            NET.addAssembly('System.Speech');
            obj = System.Speech.Synthesis.SpeechSynthesizer;
            obj.Volume = 100;
            obj.SelectVoice('Microsoft Zira Desktop');
            obj.Speak(text);
        end
        
        function answare=yesno(app,samples)
            fs=44100;
            speechObject = speechClient('Google','languageCode','en-UK');
            outInfo = speech2text(speechObject, samples, fs);
            defaultString = outInfo.Transcript;
            if defaultString=="yes"
                answare=1;
            elseif defaultString=="no"
                answare=0;
            end
        end
        
        function isQuestion = question(app,x)
            if x.PartOfSpeech(1)=='pronoun' || x.PartOfSpeech(1)=='auxiliary-verb' || x.PartOfSpeech(1)=='adverb'
                isQuestion=true;
            else
                isQuestion=false;
            end
        end
        
        function [isProperNoun]=gpn(app,x)
            for i=1:height(x)
                if x.PartOfSpeech(i)=='proper-noun'
                    isProperNoun(i)=x.Token(i);
                end
            end
            isProperNoun=isProperNoun(~cellfun('isempty',isProperNoun));
        end
        
        function [isNoun]=gn(app,x)
            for i=1:height(x)
                if x.PartOfSpeech(i)=='noun'
                    isNoun(i)=x.Token(i);
                end
            end
            isNoun=isNoun(~cellfun('isempty',isNoun));
        end
        
        function [isYear]=gy(app,x)
            for i=1:height(x)
                if x.Type(i)=='digits' && x.PartOfSpeech(i)=='numeral' && strlength(x.Token(i))<=4
                    isYear(i)=x.Token(i);
                end
            end
            isYear=isYear(~cellfun('isempty',isYear));
        end
        
        function samples=recording(app)
            f = warndlg ('NAGRYWANIE ROZPOCZĘTE','Status');
            recObj = audiorecorder(44100,16,1);
            recordblocking(recObj, 6);
            f = warndlg ('NAGRYWANIE ZAKOŃCZONE','Status');
            samples = getaudiodata(recObj);
        end
        
        function my_table=adding(app)
            
            %%%%%% MARKA I MODEL
            
            x=("Tell me the BRAND and MODEL of the car, please.");
            app.ComputerTextArea.Value=x;
            drawnow;
            Speak(app,x);
            pause(3);
            sl=recording(app);
            [sdet,defaultString] = recognition(app,sl);
            
            x=(defaultString);
            app.UserTextArea.Value=x;
            drawnow;
            
            if sdet.PartOfSpeech(1)=='proper-noun' && sdet.PartOfSpeech(2)=='proper-noun'
                brand=sdet.Token(1);
                model=sdet.Token(2);
            else
                x=("Sorry, i do not understand. Please, repeat.");
                app.ComputerTextArea.Value=x;
                Speak(app,x);
            end
            
            
            %%%%%% ROK PRODUKCJI
            
            x=("Now tell me the YEAR of car production, please.");
            app.ComputerTextArea.Value=x;
            drawnow;
            Speak(app,x);
            
            pause(3);
            sl=recording(app);
            [sdet,defaultString] = recognition(app,sl);
            
            x=(defaultString);
            app.UserTextArea.Value=x;
            drawnow;
            
            if sdet.Type(1)=='digits' && sdet.PartOfSpeech(1)=='numeral'
                year=str2double(gy(app,sdet));
            else
                x=("Sorry, i do not understand. Please, repeat.");
                app.ComputerTextArea.Value=x;
                Speak(app,x);
            end
            
            
            %%%%%% POJEMNOŚĆ
            
            x=("What is CAPACITY of this car?");
            app.ComputerTextArea.Value=x;
            drawnow;
            Speak(app,x);
            
            pause(3);
            sl=recording(app);
            [~,defaultString] = recognition(app,sl);
            
            x=(defaultString);
            app.UserTextArea.Value=x;
            drawnow;
            
            capacity=str2double(defaultString);
            
            %%%%%% MOC
            
            x=("What is HORSEPOWER of this car?");
            app.ComputerTextArea.Value=x;
            drawnow;
            Speak(app,x);
            
            pause(3);
            sl=recording(app);
            [~,defaultString] = recognition(app,sl);
            
            x=(defaultString);
            app.UserTextArea.Value=x;
            drawnow;
            
            horsepower=str2double(defaultString);
            
            %%%%%% WAGA
            
            x=("What is WEIGHT of this car?");
            app.ComputerTextArea.Value=x;
            drawnow;
            Speak(app,x);
            
            pause(3);
            sl=recording(app);
            [~,defaultString] = recognition(app,sl);
            
            x=(defaultString);
            app.UserTextArea.Value=x;
            drawnow;
            
            weight=str2double(defaultString);
            
            %%%%%% PRZYSPIESZENIE
            
            x=("What is the ACCELERATION of this car to 100 km/h?");
            app.ComputerTextArea.Value=x;
            drawnow;
            Speak(app,x);
            
            pause(3);
            sl=recording(app);
            [~,defaultString] = recognition(app,sl);
            
            x=(defaultString);
            app.UserTextArea.Value=x;
            drawnow;
            
            acceleration=str2double(defaultString);
            
            
            %%%%%% ŚREDNIE ZUŻYCIE PALIWA
            
            x=("What is the AVERAGE FUEL CONSUMPTION of this car?");
            app.ComputerTextArea.Value=x;
            drawnow;
            Speak(app,x);
            
            pause(3);
            sl=recording(app);
            [~,defaultString] = recognition(app,sl);
            
            x=(defaultString);
            app.UserTextArea.Value=x;
            drawnow;
            
            averageFuelConsumption=str2double(defaultString);
            
            
            %%%%%% WSZYSTKIE INFO ZOSTAŁY ZEBRANE, CZY ZGODNE?
            
            my_table=table(brand,model,year,capacity,horsepower,weight,acceleration,averageFuelConsumption);
            app.UITable.Visible=1;
            app.UITable.Data=my_table;
            x=("All information has been collected. Is everything right? Check all data and then press the microphone icon to answer");
            app.ComputerTextArea.Value=x;
            drawnow;
            Speak(app,x);
            
        end
        
        function my_table=datacorrect(app)
            global my_table;
            
            x=("Tell me what data is wrong.");
            app.ComputerTextArea.Value=x;
            drawnow;
            Speak(app,x);
            
            pause(3);
            sl=recording(app);
            [sdet,defaultString] = recognition(app,sl);
            
            x=(defaultString);
            app.UserTextArea.Value=x;
            drawnow;
            
            if defaultString=="brand"
                
                x=("Tell me the correct brand.");
                app.ComputerTextArea.Value=x;
                drawnow;
                Speak(app,x);
                
                pause(3);
                sl=recording(app);
                [sdet,defaultString] = recognition(app,sl);
                
                x=(defaultString);
                app.UserTextArea.Value=x;
                drawnow;
                
                my_table.brand=defaultString;
                
            elseif defaultString=="model"
                
                x=("Tell me the correct model.");
                app.ComputerTextArea.Value=x;
                drawnow;
                Speak(app,x);
                
                pause(3);
                sl=recording(app);
                [sdet,defaultString] = recognition(app,sl);
                
                x=(defaultString);
                app.UserTextArea.Value=x;
                drawnow;
                my_table.model=defaultString;
                
            elseif defaultString=="year"
                
                x=("Tell me the correct year.");
                app.ComputerTextArea.Value=x;
                drawnow;
                Speak(app,x);
                
                pause(3);
                sl=recording(app);
                [sdet,defaultString] = recognition(app,sl);
                
                x=(defaultString);
                app.UserTextArea.Value=x;
                drawnow;
                
                my_table.year=str2double(defaultString);
                
            elseif defaultString=="capacity"
                x=("Tell me the correct capacity.");
                app.ComputerTextArea.Value=x;
                drawnow;
                Speak(app,x);
                
                pause(3);
                sl=recording(app);
                [sdet,defaultString] = recognition(app,sl);
                
                x=(defaultString);
                app.UserTextArea.Value=x;
                drawnow;
                
                my_table.capacity=str2double(defaultString);
            elseif defaultString=="horsepower"
                x=("Tell me the correct horsepower.");
                app.ComputerTextArea.Value=x;
                drawnow;
                Speak(app,x);
                
                pause(3);
                sl=recording(app);
                [sdet,defaultString] = recognition(app,sl);
                
                x=(defaultString);
                app.UserTextArea.Value=x;
                drawnow;
                
                my_table.horsepower=str2double(defaultString);
            elseif defaultString=="weight"
                x=("Tell me the correct weight.");
                app.ComputerTextArea.Value=x;
                drawnow;
                Speak(app,x);
                
                pause(3);
                sl=recording(app);
                [sdet,defaultString] = recognition(app,sl);
                
                x=(defaultString);
                app.UserTextArea.Value=x;
                drawnow;
                
                my_table.weight=str2double(defaultString);
            elseif defaultString=="acceleration"
                x=("Tell me the correct acceleration.");
                app.ComputerTextArea.Value=x;
                drawnow;
                Speak(app,x);
                
                pause(3);
                sl=recording(app);
                [sdet,defaultString] = recognition(app,sl);
                
                x=(defaultString);
                app.UserTextArea.Value=x;
                drawnow;
                
                my_table.acceleration=str2double(defaultString);
            elseif sdet.Token(1)=="average"
                x=("Tell me the correct average fuel consumption.");
                app.ComputerTextArea.Value=x;
                drawnow;
                Speak(app,x);
                
                pause(3);
                sl=recording(app);
                [sdet,defaultString] = recognition(app,sl);
                
                x=(defaultString);
                app.UserTextArea.Value=x;
                drawnow;
                
                my_table.modeaverageFuelConsumptionl=str2double(defaultString);
            end
            
            app.UITable.Data=my_table;
            x=("Is everything correct now? Check all data and then press the microphone icon to answer.");
            app.ComputerTextArea.Value=x;
            drawnow;
            Speak(app,x);
            
        end
        
        function tablewr(app,table)
            
            filename = 'SportsCarKnowledgeBase.xlsx';
            writetable(table,filename);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global T;
            T = readtable('SportsCarKnowledgeBase.xlsx');
            x=("Welcome in sports car knowledge base! All car information has been loaded. What would you like to do? Would u like to ADD A NEW CAR or GET INFORMATION ABOUT CAR existing in knowledge base?");
            app.ComputerTextArea.Value=x; 
            drawnow;
            Speak(app,x);
        end

        % Button pushed function: RecOnButt
        function RecOnButtButtonPushed(app, event)
            global recObj
            warndlg ('NAGRYWANIE ROZPOCZĘTE','Status');
            recObj = audiorecorder(44100,16,1);
            record(recObj);
        end

        % Button pushed function: RecOffButt
        function RecOffButtButtonPushed(app, event)
            global recObj
            global sdetails
            global defaultString
            global my_table
            global T
            
            warndlg ('NAGRYWANIE ZAKOŃCZONE','Status');
            stop(recObj);
            samples = getaudiodata(recObj);
            
            [sdetails, defaultString] = recognition(app,samples);
            
             x=(defaultString);
             app.UserTextArea.Value=x;
            
            if sdetails.Token(1)=="add"
                my_table=adding(app);
                
            elseif sdetails.Token(1)=="get"
                [table_info,matchStr,specquest,isNoun]=getinfo(app,T);
                
                if ~isempty(specquest)==1
                
                x=join(["The ",isNoun," of ",table_info.brand,table_info.model," is",specquest,". There are all information about this car."]);
                app.ComputerTextArea.Value=x;
                app.UITable.Visible=1;
                app.UITable.Data=table_info;
                drawnow;
                Speak(app,x);    
                else
                x=join(["The ",matchStr," car is ",table_info.brand,table_info.model,". There are all information about this car."]);
                app.ComputerTextArea.Value=x;
                app.UITable.Visible=1;
                app.UITable.Data=table_info;
                drawnow;
                Speak(app,x);
                end
                
            elseif sdetails.Token(1)=="yes"
                x=("Adding a new car successful!");
                app.ComputerTextArea.Value=x;
                drawnow;
                Speak(app,x);
                Tnew=[T;my_table];
                tablewr(app,Tnew);
                
            elseif sdetails.Token(1)=="no"
                my_table=datacorrect(app);
                
            else
                x=("Sorry, i do not understand. Please, repeat.");
                app.ComputerTextArea.Value=x;
                drawnow;
                Speak(app,x);
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SPORTSCARKNOWLEDGEBASEPanel = uipanel(app.UIFigure);
            app.SPORTSCARKNOWLEDGEBASEPanel.Title = 'SPORTS CAR KNOWLEDGE BASE';
            app.ComputerTextAreaLabel = uilabel(app.SPORTSCARKNOWLEDGEBASEPanel);
            app.ComputerTextAreaLabel.Text = 'Computer';
            app.ComputerTextArea = uitextarea(app.SPORTSCARKNOWLEDGEBASEPanel);
            app.ComputerTextArea.Value = {''; ''};
            app.RecOnButt = uibutton(app.SPORTSCARKNOWLEDGEBASEPanel, 'push');
            app.RecOnButt.ButtonPushedFcn = createCallbackFcn(app, @RecOnButtButtonPushed, true);
            app.RecOnButt.Icon = 'mike_on.png';
            app.RecOnButt.Text = {''; ''};
            app.RecOffButt = uibutton(app.SPORTSCARKNOWLEDGEBASEPanel, 'push');
            app.RecOffButt.ButtonPushedFcn = createCallbackFcn(app, @RecOffButtButtonPushed, true);
            app.RecOffButt.Icon = 'mike_off.png';
            app.RecOffButt.Text = '';
            app.UITable = uitable(app.SPORTSCARKNOWLEDGEBASEPanel);
            app.UITable.ColumnName = {'Brand'; 'Model'; 'Year'; 'Capacity [ L ]'; 'Horsepower [ HP ]'; 'Weight [ KG ]'; 'Acceleration [ S ]'; 'AFC [ L / 100KM ]'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.UserTextAreaLabel = uilabel(app.SPORTSCARKNOWLEDGEBASEPanel);
            app.UserTextAreaLabel.Text = 'User';
            app.UserTextArea = uitextarea(app.SPORTSCARKNOWLEDGEBASEPanel);
            app.UserTextArea.Value = {''; ''};
            app.UserManualLabel = uilabel(app.SPORTSCARKNOWLEDGEBASEPanel);
            app.UserManualLabel.Text = 'User Manual';
            app.Label = uilabel(app.SPORTSCARKNOWLEDGEBASEPanel);
            app.Label.Text = {'After starting the program, you should press the microphone and then say whether you want to add a new car to '; 'the knowledge base or get information about the car already existing in it. The next step is to press the crossed-out '; 'microphone. Then follow the instructions given by the program. The recording starts and ends automatically - '; 'appropriate messages will be displayed.'; ''; 'Examples of available questions.'; '"What is the fastest / slowest / oldest / newest / lightest / heaviest car?"'; '"What is the most / the least economical car?"'; '"Which car has the most / the least horsepower?"'; '"Which car has the smallest / the largest capacity?"'; '"What is (brand and model of car) horesepower / capacity / car weight / acceleration / average fuel consumption?"'; ''; 'WARNING!!!'; 'All data provided when adding a new car should be spoken without units!'; ''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SportsCarKnowledgeBase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end